classdef control_panel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        button_start                 matlab.ui.control.Button
        button_stop                  matlab.ui.control.Button
        SpeedmphLabel                matlab.ui.control.Label
        gauge_speed                  matlab.ui.control.Gauge
        BatteryLifeLabel             matlab.ui.control.Label
        gauge_battery_life           matlab.ui.control.SemicircularGauge
        LowCoolantWarningLabel       matlab.ui.control.Label
        lamp_low_coolant             matlab.ui.control.Lamp
        HighTemperatureWarningLabel  matlab.ui.control.Label
        lamp_high_temperature        matlab.ui.control.Lamp
        EStopStatusLabel             matlab.ui.control.Label
        lamp_estop                   matlab.ui.control.Lamp
        lamp_start_status            matlab.ui.control.Lamp
        lamp_stop_status             matlab.ui.control.Lamp
        button_reset                 matlab.ui.control.Button
        button_distance_traveled     matlab.ui.control.Button
        label_distance_traveled      matlab.ui.control.Label
        TireRPMLabel                 matlab.ui.control.Label
        button_tire_rpm              matlab.ui.control.Button
        NormalOperationLabel         matlab.ui.control.Label
        lamp_normal_op               matlab.ui.control.Lamp
        TextArea                     matlab.ui.control.TextArea
        test_label                   matlab.ui.control.Label
    end

    
    properties (Access = private)
        %         Property1 % flags
        estop_flag = 0;     % initialize estop_flag - 1 if estop button is pressed, 0 if estop button is not pressed
        time    % time array
        t       % timer (for update GUI)
    end
    
    methods (Access = private)
        function update_data(app)
            app.gauge_speed.Value = app.RPM_array(end);
            app.test_label.Text = app.payload;
        end
        
        function read_data(app)
            app.payload_data = string(fgetl(app.arduino));
        end
    
%         function get_arduino_data(app)
% 
%             % Arduino Serial Communication
%             arduino=serial('COM6', 'Baudrate', 9600);
%             fopen(arduino);
%             
%             % rpm = zeros(1, 10000);
%             
%             for i=1:inf
%                 flushinput(arduino);
%                 
%                 payload = fgetl(arduino);
%                 %     disp(payload);
%                 split = strsplit(payload, {', ', ': '});
%                 %     disp(split)
%                 
%                 estop_status = split([find(strcmp(split, 'estop_status'))]+1);
%                 main_relay = split([find(strcmp(split, 'main_relay'))]+1);
%                 load_cell = split([find(strcmp(split, 'load_cell'))]+1);
%                 optical_counter = split([find(strcmp(split, 'optical_counter'))]+1);
%                 app.button_tire_rpm.Text = optical_counter;
%                 temp = split([find(strcmp(split, 'temp'))]+1);
%                 coolant = split([find(strcmp(split, 'coolant'))]+1);
%                 led_normal_op = split([find(strcmp(split, 'led_normal_op'))]+1);
%                 led_high_temp = split([find(strcmp(split, 'led_high_temp'))]+1);
%                 led_low_coolant = split([find(strcmp(split, 'led_low_coolant'))]+1);
%                 
%                 disp(estop_status)
%                 disp(main_relay)
%                 disp(load_cell)
%                 disp(optical_counter)
%                 disp(temp)
%                 disp(coolant)
%                 disp(led_normal_op)
%                 disp(led_high_temp)
%                 disp(led_low_coolant)
%                 
%                 fprintf('\n')
%             end
%         
%             fclose(arduino);    % close communication with Arduino
%             delete(arduino);    % delete Arduino object
%             
%             
%         end
    end
    
    methods (Access = public)
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % STARTUP FUNCTION
            %             estop_status, main_relay, load_cell, optical_counter, temp, coolant, led_normal_op, led_high_temp, led_low_coolant
            %             app.estop_flag = 0; % in estop
            clear all;clc;
            app.lamp_estop.Color = 'red';
            app.lamp_normal_op.Color = 'white';
%             arduino = serial('COM3', 'Baudrate', 9600);
%             fopen(arduino);     % begin communication with Arduino
            %                 app.gauge_speed.Value = app.optical_counter;
            %             app.TextArea.Value = estop_status + main_relay + load_cell + optical_counter + temp + coolant + led_normal_op + led_high_temp + led_low_coolant;
            %             app.button_distance_traveled.Text = string(estop_status);
            %             app.lab.Text = optical_counter;
            
            
            time_step = 0.1;
            app.time = (0 : time_step : 10)';
            app.RPM_array = strings(length(app.time), 1);
            
            app.t = timer;
%             app.t.TimerFcn = @(~, ~)app.SendData;
            app.t.TimerFcn = 'flushinput(app.arduino); app.payload_data = fgetl(app.arduino);disp(app.payload_data);';
            app.t.ExecutionMode = 'fixedRate';
            app.t.Period = time_step;
            app.t.BusyMode = 'drop';
            
            app.arduino = serial('COM6','Baudrate',9600);
        end

        % Button pushed function: button_start
        function button_startButtonPushed(app, event)
            if (app.lamp_estop.Color == [1 1 0])    % if estop lamp is yellow
                app.lamp_estop.Color = 'green';
%                 app.EStopStatusLabel.Text = 'E-Stop Clear';
%                 app.lamp_normal_op.Color = 'white';
%                 app.estop_flag = 0;     % estop is cleared
%             elseif (app.estop_flag == 1)
%                 app.lamp_estop.Color = 'white';
%                 app.estop_flag = 0;     % estop is cleared
%                 
%             else
%                 app.lamp_estop.Color = 'blue';
            end
%             
%             get_arduino_data(app);

        
        if strcmp(get(app.t,'running'), 'off')
            disp('helloworld');

            fopen(app.arduino);
            start(app.t);
%             read_data(arduino);
%             app.payload = fgetl(app.arduino);
%             fprintf(app.payload);
        end
        end

        % Button pushed function: button_stop
        function button_stopButtonPushed(app, event)
            app.lamp_estop.Color = 'red';
            app.estop_flag = 1;     % estop button is pressed
            app.EStopStatusLabel.Text = 'In E-Stop';
            app.lamp_normal_op.Color = 'black';
            
            
            if strcmp(get(app.t,'running'), 'on')
                stop(app.t);
                delete(app.t);
                fclose(app.arduino);
                delete(app.arduino);
            end
        end

        % Button pushed function: button_reset
        function button_resetButtonPushed(app, event)
            %             estop_reset_flag = true;
            app.lamp_estop.Color = 'yellow';
            app.EStopStatusLabel.Text = 'Reset';
            app.estop_flag = 0;     % estop is cleared
        end

        % Callback function
        function Slider1ValueChanged(app, event)
            % testing purposes only
            value = app.Slider1.Value;
            app.gauge_speed.Value = value;
        end

        % Callback function
        function Slider1ValueChanged2(app, event)
            % testing purposes only
            value = app.Slider1.Value;
            %             app.gauge_speed.Value = value;
            %             app.button_distance_traveled.Text = string(value);
        end

        % Button pushed function: button_tire_rpm
        function button_tire_rpmButtonPushed(app, event)
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % shutdown script
            delete(app.t);
            delete(app)
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.button_start = uibutton(app.UIFigure, 'push');
            app.button_start.ButtonPushedFcn = createCallbackFcn(app, @button_startButtonPushed, true);
            app.button_start.Text = 'START';
            app.button_stop = uibutton(app.UIFigure, 'push');
            app.button_stop.ButtonPushedFcn = createCallbackFcn(app, @button_stopButtonPushed, true);
            app.button_stop.Text = 'STOP';
            app.SpeedmphLabel = uilabel(app.UIFigure);
            app.SpeedmphLabel.Text = 'Speed (mph)';
            app.gauge_speed = uigauge(app.UIFigure, 'circular');
            app.gauge_speed.Limits = [0 200];
            app.gauge_speed.MajorTicks = [0 20 40 60 80 100 120 140 160 180 200];
            app.BatteryLifeLabel = uilabel(app.UIFigure);
            app.BatteryLifeLabel.Text = 'Battery Life';
            app.gauge_battery_life = uigauge(app.UIFigure, 'semicircular');
            app.LowCoolantWarningLabel = uilabel(app.UIFigure);
            app.LowCoolantWarningLabel.Text = 'Low Coolant Warning';
            app.lamp_low_coolant = uilamp(app.UIFigure);
            app.HighTemperatureWarningLabel = uilabel(app.UIFigure);
            app.HighTemperatureWarningLabel.Text = 'High Temperature Warning';
            app.lamp_high_temperature = uilamp(app.UIFigure);
            app.EStopStatusLabel = uilabel(app.UIFigure);
            app.EStopStatusLabel.Text = 'E-Stop Status';
            app.lamp_estop = uilamp(app.UIFigure);
            app.lamp_start_status = uilamp(app.UIFigure);
            app.lamp_stop_status = uilamp(app.UIFigure);
            app.button_reset = uibutton(app.UIFigure, 'push');
            app.button_reset.ButtonPushedFcn = createCallbackFcn(app, @button_resetButtonPushed, true);
            app.button_reset.Text = 'RESET';
            app.button_distance_traveled = uibutton(app.UIFigure, 'push');
            app.button_distance_traveled.Text = '';
            app.label_distance_traveled = uilabel(app.UIFigure);
            app.label_distance_traveled.Text = 'Distance Traveled (miles)';
            app.TireRPMLabel = uilabel(app.UIFigure);
            app.TireRPMLabel.Text = 'Tire RPM';
            app.button_tire_rpm = uibutton(app.UIFigure, 'push');
            app.button_tire_rpm.ButtonPushedFcn = createCallbackFcn(app, @button_tire_rpmButtonPushed, true);
            app.button_tire_rpm.Text = '';
            app.NormalOperationLabel = uilabel(app.UIFigure);
            app.NormalOperationLabel.Text = 'Normal Operation';
            app.lamp_normal_op = uilamp(app.UIFigure);
            app.TextArea = uitextarea(app.UIFigure);
            app.test_label = uilabel(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = control_panel

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end