classdef while_loop_panel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        button_start  matlab.ui.control.Button
        GaugeLabel    matlab.ui.control.Label
        gauge_speed   matlab.ui.control.Gauge
        button_stop   matlab.ui.control.Button
        button_rpm    matlab.ui.control.Button
    end

    
    properties (Access = private)
        read_in_data % read in data flag
        ser
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            close;clear;clc;
            global ser;
            ser = serial('COM6', 'BaudRate', 9600);
            fopen(ser);
            pause(0.5);
            disp('serial opened');
        end

        % Button pushed function: button_start
        function button_startButtonPushed(app, event)
            disp('start button pushed');
            app.read_in_data = 1;
            global ser;
            while app.read_in_data == 1
%                 flushinput(ser);
                payload = fgetl(ser);
%                 disp(payload)
                split = strsplit(payload, {', ', ': '});
                
                estop_status = split([find(strcmp(split, 'estop_status'))]+1);
                main_relay = split([find(strcmp(split, 'main_relay'))]+1);
                load_cell = split([find(strcmp(split, 'load_cell'))]+1);
                optical_counter = split([find(strcmp(split, 'optical_counter'))]+1);
                temp = split([find(strcmp(split, 'temp'))]+1);
                coolant = split([find(strcmp(split, 'coolant'))]+1);
                led_normal_op = split([find(strcmp(split, 'led_normal_op'))]+1);
                led_high_temp = split([find(strcmp(split, 'led_high_temp'))]+1);
                led_low_coolant = split([find(strcmp(split, 'led_low_coolant'))]+1);
                
               estop_status_str = char(estop_status);
                if strcmp(estop_status_str, 'estop')
                    disp('estop!!!!!!')
                end
                
                main_relay_str = char(main_relay);
                if strcmp(main_relay_str, 'estop')
                    disp('relay estop!!!!!!')
                end
                
                
                split = str2double(split);
                
                load_cell_str = num2str(split(6));
                optical_counter_str = num2str(split(8));
                
                app.button_rpm.Text = optical_counter_str;
                refresh(app)
%                 disp(estop_status)
%                 disp(main_relay)
%                 disp(load_cell)
%                 disp(optical_counter)
%                 disp(temp)
%                 disp(coolant)
%                 disp(led_normal_op)
%                 disp(led_high_temp)
%                 disp(led_low_coolant)


                if app.read_in_data == 0
                    fclose(ser);
                    delete(ser);
                end
            end
        end

        % Button pushed function: button_stop
        function button_stopButtonPushed(app, event)
            disp('stop button pushed');
            app.read_in_data = 0;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            global ser;
            fclose(ser);
            pause(0.2);
            delete(ser);
            instrreset
            delete(app)
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.button_start = uibutton(app.UIFigure, 'push');
            app.button_start.ButtonPushedFcn = createCallbackFcn(app, @button_startButtonPushed, true);
            app.button_start.Text = 'Start';
            app.button_stop = uibutton(app.UIFigure, 'push');
            app.button_stop.ButtonPushedFcn = createCallbackFcn(app, @button_stopButtonPushed, true);
            app.button_stop.Text = 'Stop';
            app.GaugeLabel = uilabel(app.UIFigure);
            app.GaugeLabel.Text = 'Gauge';
            app.gauge_speed = uigauge(app.UIFigure, 'circular');
            app.gauge_speed.Limits = [0 140];
            app.button_rpm = uibutton(app.UIFigure, 'push');
            app.button_rpm.Text = 'RPM';
        end
    end

    methods (Access = public)

        % Construct app
        function app = while_loop_panel

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end