classdef while_loop_panel_006 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        button_start                  matlab.ui.control.Button
        button_stop                   matlab.ui.control.Button
        SpeedmphLabel                 matlab.ui.control.Label
        gauge_speed                   matlab.ui.control.Gauge
        button_rpm                    matlab.ui.control.Button
        NormalOperationLabel          matlab.ui.control.Label
        lamp_normal_op                matlab.ui.control.Lamp
        LowCoolantWarningLabel        matlab.ui.control.Label
        lamp_low_coolant              matlab.ui.control.Lamp
        HighTemperatureWarningLampLabel  matlab.ui.control.Label
        lamp_high_temperature         matlab.ui.control.Lamp
        button_reset                  matlab.ui.control.Button
        ESTOPSTATUSLabel              matlab.ui.control.Label
        lamp_estop_status             matlab.ui.control.Lamp
        label_rpm                     matlab.ui.control.Label
        label_distance_traveled       matlab.ui.control.Label
        button_distance_traveled      matlab.ui.control.Button
        BatteryLifeGaugeLabel         matlab.ui.control.Label
        gauge_battery_life            matlab.ui.control.SemicircularGauge
        SerialCommunicationLampLabel  matlab.ui.control.Label
        lamp_serial_comm              matlab.ui.control.Lamp
        RUNSTATUSLabel                matlab.ui.control.Label
        lamp_run_status               matlab.ui.control.Lamp
        FanThresholdLabel             matlab.ui.control.Label
        field_max_rpm                 matlab.ui.control.NumericEditField
        CalibrationFactorLabel        matlab.ui.control.Label
        field_max_calib_factor        matlab.ui.control.NumericEditField
        button_send_rpmload           matlab.ui.control.Button
        label_distance_traveled_2     matlab.ui.control.Label
    end

    
    properties (Access = private)
        read_in_data    % begin read in data flag
        serial_device_available_flag
        max_rpm_i=100;       % integer value ( -> DAQ -> main_controller)
        max_calib_factor_i=100;      % integer value ( -> DAQ -> main_controller)
        start_stop_i=0;      % integer value ( -> DAQ -> main_controller)
        
        estop_status_flag
        dist_travelled = 0;
        
        wheel_circumference = .0020106; %201060;  % 6.4*pi*10000 - in km
        
        ser             % serial device object
    end
    
    methods (Access = private)
        
        function estop_color_change(app)
            if app.estop_status_flag == 1
                app.lamp_estop_status.Color = [1 0 0];
            else
                app.lamp_estop_status.Color = [1 1 1];
            end
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            close;clear;clc;
            global ser;
            if isempty(seriallist)
                disp('no serial device connected')
                app.serial_device_available_flag = 0;
%                 app.lamp_serial_comm.Color = [1 0 0];
%                 drawnow
            else
                ser = serial('COM8', 'BaudRate', 9600);
                try
                    fopen(ser);
                    ser.ReadAsyncMode='manual';
                    disp('serial opened');
                    app.serial_device_available_flag = 1;
%                     app.lamp_serial_comm.Color = [0 1 0];
%                     drawnow
                catch
                    warning('Error using serial/fopen (line 72)');
                    disp('failed to open serial comms')
                    closereq;
                    errordlg('Failed to open serial comms. Restart app.', 'Serial Communication Failed');
                end
                
            end
        end

        % Button pushed function: button_start
        function button_startButtonPushed(app, event)
            disp('START BUTTON');
            global ser;
            if (app.lamp_run_status.Color == [1 1 0])
                app.lamp_run_status.Color = [0 1 0];
                app.lamp_serial_comm.Color = [0 1 0];
                app.read_in_data = 1;
                
                app.start_stop_i = 1;
                if (app.max_rpm_i ~= 0 && app.max_calib_factor_i ~= 0)
                    fprintf(ser, '%01i,%04i,%07i*', [app.start_stop_i, app.max_rpm_i, app.max_calib_factor_i]);
                else
                    fprintf(ser, '%01i,%04i,%07i*', [app.start_stop_i, 100, 626660]);
                end
                
                
%                 fwrite(ser, 'start');
                drawnow
            else
                app.read_in_data = 0;
                app.start_stop_i = 0;
            end
            

            while app.read_in_data == 1
                flushinput(ser);
                
                str = 'estop_status';
                payload = fgetl(ser);
                if strfind(payload, str)
%                     disp(payload)
                    split = strsplit(payload, {', ', ': '});
                    
                    estop_status = split([find(strcmp(split, 'estop_status'))]+1);
                    main_relay = split([find(strcmp(split, 'main_relay'))]+1);
                    load_cell = split([find(strcmp(split, 'load_cell'))]+1);
                    optical_counter = split([find(strcmp(split, 'optical_counter'))]+1);
                    temp = split([find(strcmp(split, 'temp'))]+1);
                    coolant = split([find(strcmp(split, 'coolant'))]+1);
                    lamp_normal_op = split([find(strcmp(split, 'led_normal_op'))]+1);
                    led_high_temp = split([find(strcmp(split, 'led_high_temp'))]+1);
                    lamp_low_coolant = split([find(strcmp(split, 'led_low_coolant'))]+1);
                    battery_voltage = split([find(strcmp(split, 'batt_voltage'))]+1);
                    
                    estop_status_str = char(estop_status);
                    if strcmp(estop_status_str, 'estop')
                        app.estop_status_flag = 1;
                        app.lamp_estop_status.Color = [1 0 0];
                    else
                        app.estop_status_flag = 0;
                        app.lamp_estop_status.Color = [0 1 0];
                    end
    %                 estop_color_change(app)
                    
                    main_relay_str = char(main_relay);
    %                 if strcmp(main_relay_str, 'estop')
    % %                     relay in estop
    %                 end
                    
                    lamp_normal_op_str = char(lamp_normal_op);
                    if strcmp(lamp_normal_op_str, 'on')
                        app.lamp_normal_op.Color = [1 1 1];
                    else
                        app.lamp_normal_op.Color = [0 0 0];
                    end
                    
                    led_high_temp_str = char(led_high_temp);
                    if strcmp(led_high_temp_str, 'on')
                        app.lamp_high_temperature.Color = [1 0 0];
                    else
                        app.lamp_high_temperature.Color = [0 0 0];
                    end
                    
                    lamp_low_coolant_str = char(lamp_low_coolant);
                    if contains(lamp_low_coolant_str, 'on')
                        app.lamp_low_coolant.Color = [0 0 1];
                    else
                        app.lamp_low_coolant.Color = [0 0 0];
                    end
                        
                    
                    split = str2double(split);
    %                 load_cell_str = num2str(split(6));
                    optical_counter_str = num2str(split(8)); % 8
                    
                    app.button_rpm.Text = optical_counter_str;
                    app.gauge_speed.Value = str2num(optical_counter_str);
                    
                    battery_voltage_str = num2str(split(20));   % 20
                    app.gauge_battery_life.Value = str2num(battery_voltage_str);
                    
                    if strcmp(estop_status_str, 'ok')
                        app.dist_travelled = app.dist_travelled + (str2num(optical_counter_str) * app.wheel_circumference) / 60;
%                     disp(app.dist_travelled)
                        app.button_distance_traveled.Text = num2str(app.dist_travelled);
                    else
                        app.dist_travelled = app.dist_travelled;
                    end
                    
    %                 disp(estop_status)
    %                 disp(main_relay)
    %                 disp(load_cell)
    %                 disp(optical_counter)
    %                 disp(temp)
    %                 disp(coolant)
    %                 disp(led_normal_op)
    %                 disp(led_high_temp)
    %                 disp(led_low_coolant)
                    
                    if app.read_in_data == 0
                        fclose(ser);
                        delete(ser);
                    end
                    drawnow
                end
            end
        end

        % Button pushed function: button_stop
        function button_stopButtonPushed(app, event)
            disp('STOP BUTTON');
            global ser;
%             fwrite(ser, 'stop');
            fprintf(ser, '%01i,%04i,%07i*', [0, app.max_rpm_i, app.max_calib_factor_i]);
            app.read_in_data = 0;
            app.lamp_serial_comm.Color = [1 0 0];
            app.lamp_run_status.Color = [1 0 0];
            
            app.start_stop_i = 0;

            drawnow
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            global ser;
            if app.serial_device_available_flag == 1
                app.read_in_data = 0;
                fclose(ser);
                pause(0.2);
                delete(ser);
            end
            instrreset
            delete(app)
            
        end

        % Button pushed function: button_reset
        function button_resetButtonPushed(app, event)
            % reset button pushed
            disp('RESET BUTTON');
            app.lamp_run_status.Color = [1 1 0];   % yellow
            drawnow
        end

        % Value changed function: field_max_rpm
        function field_max_rpmValueChanged(app, event)
            value = app.field_max_rpm.Value;
            disp(value);
            app.max_rpm_i = value;
        end

        % Value changed function: field_max_calib_factor
        function field_max_calib_factorValueChanged(app, event)
            value = app.field_max_calib_factor.Value;
            disp(value);
            app.max_calib_factor_i = value;
        end

        % Button pushed function: button_send_rpmload
        function button_send_rpmloadButtonPushed(app, event)
            global ser;
            if (app.max_rpm_i ~= 0 && app.max_calib_factor_i ~= 0)
                fprintf(ser, '%01i,%04i,%07i*', [app.start_stop_i, app.max_rpm_i, app.max_calib_factor_i]);
            else
                fprintf(ser, '%01i,%04i,%07i*', [app.start_stop_i, 100, 626660]);
            end            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.button_start = uibutton(app.UIFigure, 'push');
            app.button_start.ButtonPushedFcn = createCallbackFcn(app, @button_startButtonPushed, true);
            app.button_start.Text = 'START';
            app.button_stop = uibutton(app.UIFigure, 'push');
            app.button_stop.ButtonPushedFcn = createCallbackFcn(app, @button_stopButtonPushed, true);
            app.button_stop.Text = 'STOP';
            app.SpeedmphLabel = uilabel(app.UIFigure);
            app.SpeedmphLabel.Text = 'Speed (mph)';
            app.gauge_speed = uigauge(app.UIFigure, 'circular');
            app.gauge_speed.Limits = [0 140];
            app.button_rpm = uibutton(app.UIFigure, 'push');
            app.button_rpm.Text = 'RPM';
            app.NormalOperationLabel = uilabel(app.UIFigure);
            app.NormalOperationLabel.Text = 'Normal Operation';
            app.lamp_normal_op = uilamp(app.UIFigure);
            app.LowCoolantWarningLabel = uilabel(app.UIFigure);
            app.LowCoolantWarningLabel.Text = 'Low Coolant Warning';
            app.lamp_low_coolant = uilamp(app.UIFigure);
            app.HighTemperatureWarningLampLabel = uilabel(app.UIFigure);
            app.HighTemperatureWarningLampLabel.Text = 'High Temperature Warning';
            app.lamp_high_temperature = uilamp(app.UIFigure);
            app.button_reset = uibutton(app.UIFigure, 'push');
            app.button_reset.ButtonPushedFcn = createCallbackFcn(app, @button_resetButtonPushed, true);
            app.button_reset.Text = 'RESET';
            app.ESTOPSTATUSLabel = uilabel(app.UIFigure);
            app.ESTOPSTATUSLabel.Text = 'E-STOP STATUS';
            app.lamp_estop_status = uilamp(app.UIFigure);
            app.label_rpm = uilabel(app.UIFigure);
            app.label_rpm.Text = 'TIRE RPM';
            app.label_distance_traveled = uilabel(app.UIFigure);
            app.label_distance_traveled.Text = 'DISTANCE TRAVELED';
            app.button_distance_traveled = uibutton(app.UIFigure, 'push');
            app.button_distance_traveled.Text = 'distance_traveled';
            app.BatteryLifeGaugeLabel = uilabel(app.UIFigure);
            app.BatteryLifeGaugeLabel.Text = 'Battery Life';
            app.gauge_battery_life = uigauge(app.UIFigure, 'semicircular');
            app.SerialCommunicationLampLabel = uilabel(app.UIFigure);
            app.SerialCommunicationLampLabel.Text = 'Serial Communication';
            app.lamp_serial_comm = uilamp(app.UIFigure);
            app.RUNSTATUSLabel = uilabel(app.UIFigure);
            app.RUNSTATUSLabel.Text = 'RUN STATUS';
            app.lamp_run_status = uilamp(app.UIFigure);
            app.FanThresholdLabel = uilabel(app.UIFigure);
            app.FanThresholdLabel.Text = 'Fan Threshold';
            app.field_max_rpm = uieditfield(app.UIFigure, 'numeric');
            app.field_max_rpm.Limits = [0 Inf];
            app.field_max_rpm.ValueChangedFcn = createCallbackFcn(app, @field_max_rpmValueChanged, true);
            app.CalibrationFactorLabel = uilabel(app.UIFigure);
            app.CalibrationFactorLabel.Text = 'Calibration Factor  (+)';
            app.field_max_calib_factor = uieditfield(app.UIFigure, 'numeric');
            app.field_max_calib_factor.Limits = [0 Inf];
            app.field_max_calib_factor.ValueChangedFcn = createCallbackFcn(app, @field_max_calib_factorValueChanged, true);
            app.button_send_rpmload = uibutton(app.UIFigure, 'push');
            app.button_send_rpmload.ButtonPushedFcn = createCallbackFcn(app, @button_send_rpmloadButtonPushed, true);
            app.button_send_rpmload.Text = 'Send Data';
            app.label_distance_traveled_2 = uilabel(app.UIFigure);
            app.label_distance_traveled_2.Text = 'km';
        end
    end

    methods (Access = public)

        % Construct app
        function app = while_loop_panel_006

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end